classdef GuiLakeshore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure            matlab.ui.Figure
        SensorPanel_1         matlab.ui.container.Panel
        TLabel                matlab.ui.control.Label
        TDisp_1               matlab.ui.control.EditField
        SensorPanel_2         matlab.ui.container.Panel
        TLabel_3              matlab.ui.control.Label
        TDisp_2               matlab.ui.control.EditField
        LoggerButton          matlab.ui.control.Button
        SinglereadButton      matlab.ui.control.Button
        SensorPanel_3         matlab.ui.container.Panel
        TLabel_2              matlab.ui.control.Label
        TDisp_3               matlab.ui.control.EditField
        SensorPanel_4         matlab.ui.container.Panel
        TLabel_4              matlab.ui.control.Label
        TDisp_4               matlab.ui.control.EditField
        TemperatureunitLabel  matlab.ui.control.Label
        TUnitDropDown         matlab.ui.control.DropDown
        HeaterPanel_1         matlab.ui.container.Panel
        HeaterrangeLabel      matlab.ui.control.Label
        HeaterRangeDisp_1     matlab.ui.control.EditField
    end


    properties (Access = public)
        name = 'Lakeshore temperature controller';
        Instr; % MyInstrument object
        linked_elem_list={}; % Lust of elements
        Lg; % MyLogger object
        GuiLg; % Gui of MyLogger
    end

    properties (Access = private)
        Llh % Listener to new data, generated by logger
        Obdlh % Listener to logger being closed
    end
    

    methods (Access = private)
    
        function createControlLinks(app)
            app.TDisp_1.Tag = 'temp_str';
            addprop(app.TDisp_1,'OutputProcessingFcn');
            app.TDisp_1.OutputProcessingFcn = @(x)x{1};
            app.linked_elem_list = [app.linked_elem_list, app.TDisp_1];
            
            app.TDisp_2.Tag = 'temp_str';
            addprop(app.TDisp_2,'OutputProcessingFcn');
            app.TDisp_2.OutputProcessingFcn = @(x)x{2};
            app.linked_elem_list = [app.linked_elem_list, app.TDisp_2];
            
            app.TDisp_3.Tag = 'temp_str';
            addprop(app.TDisp_3,'OutputProcessingFcn');
            app.TDisp_3.OutputProcessingFcn = @(x)x{3};
            app.linked_elem_list = [app.linked_elem_list, app.TDisp_3];
            
            app.TDisp_4.Tag = 'temp_str';
            addprop(app.TDisp_4,'OutputProcessingFcn');
            app.TDisp_4.OutputProcessingFcn = @(x)x{4};
            app.linked_elem_list = [app.linked_elem_list, app.TDisp_4];
            
            app.HeaterRangeDisp_1.Tag = 'heater_rng_str';
            addprop(app.HeaterRangeDisp_1,'OutputProcessingFcn');
            app.HeaterRangeDisp_1.OutputProcessingFcn = @(x)x{1};
            app.linked_elem_list = [app.linked_elem_list, app.HeaterRangeDisp_1];
            
            app.TUnitDropDown.Tag = 'temp_unit';
            app.linked_elem_list = [app.linked_elem_list, app.TUnitDropDown];
        end
        
        function updateDisp(app)
            try 
                app.SensorPanel_1.Title = sprintf('Sensor A: %s',...
                    app.Instr.inp_sens_names{1});
                app.SensorPanel_2.Title = sprintf('Sensor B: %s',...
                    app.Instr.inp_sens_names{2});
                app.SensorPanel_3.Title = sprintf('Sensor C: %s',...
                    app.Instr.inp_sens_names{3});
                app.SensorPanel_4.Title = sprintf('Sensor D: %s',...
                    app.Instr.inp_sens_names{4});
            catch
                warning('Cannot display sensor names')
            end
        end
        
        function newDataCallback(app,~,~)
            updateDisp(app);
            updateGui(app);
        end
    
        function loggerClosedCallback(app,~,~)
            % Clean up
            delete(app.Llh);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            p=inputParser();
            p.KeepUnmatched = true;
            addParameter(p,'Instr',[]);
            addParameter(p,'name',[]);
            parse(p, varargin{:});
            
            if ~ismember('Instr',p.UsingDefaults)
                app.Instr = p.Results.Instr;
            else
                % Create a dummy instrument object if it was not supplied externally
                app.Instr = MyLakeshore336('visa','');
            end
            if ~ismember('name',p.UsingDefaults)
                app.name = p.Results.name;
            end
            
            readAllHedged(app.Instr);
            
            app.MainFigure.Name = app.name;
            createControlLinks(app);
            updateGui(app);
            updateDisp(app);
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            try
                delete(app.Instr);
            catch
            end
            try
                delete(app.GuiLg);
            catch
            end
            try
                delete(app.Lg);
            catch
            end
            delete(app)
        end

        % Button pushed function: SinglereadButton
        function SinglereadCallback(app, event)
            readAllHedged(app.Instr);
            updateGui(app);
            updateDisp(app);
        end

        % Button pushed function: LoggerButton
        function LoggerButtonPushed(app, event)
            if ~(isa(app.Lg, 'MyLogger')&&isvalid(app.Lg))
                tu = app.Instr.temp_unit;
                names= app.Instr.inp_sens_names;
                headers = {['T ch A ',names{1},' [',tu,']'],...
                    ['T ch B ',names{2},' [',tu,']'],...
                    ['T ch C ',names{3},' [',tu,']'],...
                    ['T ch D ',names{4},' [',tu,']']};
                app.Lg = MyLogger('MeasFcn', @()readAllHedged(app.Instr),...
                    'data_headers', headers,...
                    'disp_fmt', '%15s: %.3f');
                app.GuiLg = GuiLogger('Lg',app.Lg,'name','Temperature logger');
                app.Llh = addlistener(app.Lg, 'NewData', @app.newDataCallback);
                app.Obdlh = addlistener(app.GuiLg, 'ObjectBeingDestroyed',...
                    @app.loggerClosedCallback);
            else
                % bring logger gui to front
                fig = findfigure(app.GuiLg);
                fig.Visible = 'off';
                fig.Visible = 'on';
            end
        end

        % Value changed function: TUnitDropDown
        function TUnitDropDownValueChanged(app, event)
            value = app.TUnitDropDown.Value;
            app.Instr.temp_unit = value;
            readAllHedged(app.Instr);
            updateGui(app);
            updateDisp(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure;
            app.MainFigure.Name = 'TPG pressure gauge controller';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.SensorPanel_1 = uipanel(app.MainFigure);
            app.SensorPanel_1.Title = 'Sensor A';
            app.TLabel = uilabel(app.SensorPanel_1);
            app.TLabel.Text = 'T';
            app.TDisp_1 = uieditfield(app.SensorPanel_1, 'text');
            app.TDisp_1.Editable = 'off';
            app.SensorPanel_2 = uipanel(app.MainFigure);
            app.SensorPanel_2.Title = 'Sensor B';
            app.TLabel_3 = uilabel(app.SensorPanel_2);
            app.TLabel_3.Text = 'T';
            app.TDisp_2 = uieditfield(app.SensorPanel_2, 'text');
            app.TDisp_2.Editable = 'off';
            app.LoggerButton = uibutton(app.MainFigure, 'push');
            app.LoggerButton.ButtonPushedFcn = createCallbackFcn(app, @LoggerButtonPushed, true);
            app.LoggerButton.Text = 'Logger';
            app.SinglereadButton = uibutton(app.MainFigure, 'push');
            app.SinglereadButton.ButtonPushedFcn = createCallbackFcn(app, @SinglereadCallback, true);
            app.SinglereadButton.Text = 'Single read';
            app.SensorPanel_3 = uipanel(app.MainFigure);
            app.SensorPanel_3.Title = 'Sensor C';
            app.TLabel_2 = uilabel(app.SensorPanel_3);
            app.TLabel_2.Text = 'T';
            app.TDisp_3 = uieditfield(app.SensorPanel_3, 'text');
            app.TDisp_3.Editable = 'off';
            app.SensorPanel_4 = uipanel(app.MainFigure);
            app.SensorPanel_4.Title = 'Sensor D';
            app.TLabel_4 = uilabel(app.SensorPanel_4);
            app.TLabel_4.Text = 'T';
            app.TDisp_4 = uieditfield(app.SensorPanel_4, 'text');
            app.TDisp_4.Editable = 'off';
            app.TemperatureunitLabel = uilabel(app.MainFigure);
            app.TemperatureunitLabel.Text = 'Temperature unit';
            app.TUnitDropDown = uidropdown(app.MainFigure);
            app.TUnitDropDown.Items = {'K', 'C'};
            app.TUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @TUnitDropDownValueChanged, true);
            app.TUnitDropDown.Value = 'K';
            app.HeaterPanel_1 = uipanel(app.MainFigure);
            app.HeaterPanel_1.Title = 'Out 1';
            app.HeaterrangeLabel = uilabel(app.HeaterPanel_1);
            app.HeaterrangeLabel.Text = 'Heater range';
            app.HeaterRangeDisp_1 = uieditfield(app.HeaterPanel_1, 'text');
            app.HeaterRangeDisp_1.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiLakeshore(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end