classdef GuiLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoggerUIFigure         matlab.ui.Figure
        DisplaytracesMenu      matlab.ui.container.Menu
        Menu_2                 matlab.ui.container.Menu
        AxesMenu               matlab.ui.container.Menu
        Menu_3                 matlab.ui.container.Menu
        Axes                   matlab.ui.control.UIAxes
        FileEditFieldLabel     matlab.ui.control.Label
        FileEditField          matlab.ui.control.EditField
        LegendArea             matlab.ui.control.TextArea
        TimelabelsPanel        matlab.ui.container.Panel
        AddButton              matlab.ui.control.Button
        SelectedDropDownLabel  matlab.ui.control.Label
        SelectedTlDropDown     matlab.ui.control.DropDown
        DeleteTlButton         matlab.ui.control.Button
        ModifyTlButton         matlab.ui.control.Button
        DisplayTlCheckBox      matlab.ui.control.CheckBox
        MeasurementPanel       matlab.ui.container.Panel
        MeasButton             matlab.ui.control.StateButton
        IntervalsLabel         matlab.ui.control.Label
        MeasperiodsEditField   matlab.ui.control.NumericEditField
        SavecontCheckBox       matlab.ui.control.CheckBox
        PlotlimitsPanel        matlab.ui.container.Panel
        tminEditFieldLabel     matlab.ui.control.Label
        tminEditField          matlab.ui.control.EditField
        tmaxEditFieldLabel     matlab.ui.control.Label
        tmaxEditField          matlab.ui.control.EditField
        yminEditFieldLabel     matlab.ui.control.Label
        yminEditField          matlab.ui.control.NumericEditField
        ymaxEditFieldLabel     matlab.ui.control.Label
        ymaxEditField          matlab.ui.control.NumericEditField
        DisplaytracesPanel     matlab.ui.container.Panel
        DispTraceCheckBox_1    matlab.ui.control.CheckBox
        DispTraceCheckBox_2    matlab.ui.control.CheckBox
        DispTraceCheckBox_3    matlab.ui.control.CheckBox
        DispTraceCheckBox_4    matlab.ui.control.CheckBox
        DispTraceCheckBox_5    matlab.ui.control.CheckBox
        DispTraceCheckBox_6    matlab.ui.control.CheckBox
        DispTraceCheckBox_7    matlab.ui.control.CheckBox
        DispTraceCheckBox_8    matlab.ui.control.CheckBox
        DispTraceCheckBox_9    matlab.ui.control.CheckBox
        DispTraceCheckBox_10   matlab.ui.control.CheckBox
        YscaleDropDownLabel    matlab.ui.control.Label
        YscaleDropDown         matlab.ui.control.DropDown
        RecordPanel            matlab.ui.container.Panel
        LoadButton             matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        LimitmodeLabel         matlab.ui.control.Label
        XLimModeDropDown       matlab.ui.control.DropDown
        LimitmodeLabel_2       matlab.ui.control.Label
        YLimModeDropDown       matlab.ui.control.DropDown
        AutoscaleYCheckBox     matlab.ui.control.CheckBox
        AutoscaleXCheckBox     matlab.ui.control.CheckBox
    end


    properties (Access = public)
        GuiSync
        
        % Logger object
        Lg
    end
    
    properties (Access = private)
        
        % Handles to the data lines plotted in Axes
        Pl
        
        % Initial axes size and position
        axes_init_pos
        init_panel_h
    end
    
    methods (Access = public)
        function cb = publicCreateCallbackFcn(app, f)
            cb = createCallbackFcn(app, f, true);
        end
    end

    methods (Access = private)
    
        function createLinks(app)
            addLink(app.GuiSync, app.FileEditField,        'Lg.Record.file_name');
            addLink(app.GuiSync, app.SavecontCheckBox,     'Lg.save_cont');   
            addLink(app.GuiSync, app.MeasperiodsEditField, 'Lg.MeasTimer.Period');
            
            addLink(app.GuiSync, app.MeasButton,           'Lg.MeasTimer.Running', ...
                'OutputProcessingFcn', @app.setMeasButton);
            
            addLink(app.GuiSync, app.tminEditField,         'Axes.XLim(1)',...
                'OutputProcessingFcn', @(x)datestr(x));  
            addLink(app.GuiSync, app.tmaxEditField,         'Axes.XLim(2)',...
                'OutputProcessingFcn', @(x)datestr(x));
            
            addLink(app.GuiSync, app.XLimModeDropDown,      'Axes.XLimMode');
            
            addLink(app.GuiSync, app.yminEditField,         'Axes.YLim(1)');
            addLink(app.GuiSync, app.ymaxEditField,         'Axes.YLim(2)');
            addLink(app.GuiSync, app.YLimModeDropDown,      'Axes.YLimMode');
            
            addLink(app.GuiSync, app.YscaleDropDown,        'Axes.YScale');
            
            % For the Display Trace panel checkboxes add indices as tags for them to be identifiable
            % This is needed in order to use a single callback for all the boxes 
            for i=1:10
                tmpname = sprintf('DispTraceCheckBox_%i',i);
                app.(tmpname).Tag = sprintf('%i',i);
            end
        end
    
        function updateGui(app)
            updateDispTracesPanel(app);
            updateTimeLabelDropDown(app);
        end
    
        function newDataCallback(app, ~, ~)
            updateDataDisp(app);
            updateLinkedElements(app.GuiSync);
        end
        
        function updateDataDisp(app)
            
            % Plot using MyLog method
            app.Pl = plot(app.Lg.Record, app.Axes, ...
                'time_labels', app.DisplayTlCheckBox.Value);
            
            app.LegendArea.Value = printReading(app.Lg);
            
            % Update the Display Trace panel
            % Only change the visibility if necessary 
            % in order to save some rendering time
            for i=1:10
                tmpname = sprintf('DispTraceCheckBox_%i',i);
                if i<=length(app.Pl)
                    setIfChanged(app.(tmpname), 'Visible', 'on');
                else
                    setIfChanged(app.(tmpname), 'Visible', 'off');
                end
            end
        end
    
        function updateDispTracesPanel(app)
            
            % The number of data lines plotted is equal to the number
            % of columns in the log. Number of checkboxes is limited to 10.
            imax = min(length(app.Pl), 10);
            for i=1:imax
                tmpname = sprintf('DispTraceCheckBox_%i',i);
                isdisp = strcmpi(app.Pl(i).Visible,'on');
                
                setIfChanged(app.(tmpname), 'Value', isdisp);
            end
        end
    
        function updateTimeLabelDropDown(app)
            time_label_titles = printTimeLabelList(app.Lg.Record);
            
            is_changed = setIfChanged(app.SelectedTlDropDown, 'Items', time_label_titles);
            if is_changed
                
                % Use ItemsData to store indices corresponding to the menu entries
                app.SelectedTlDropDown.ItemsData = 1:length(time_label_titles);
            end
        end
    
        function is_on = setMeasButton(app, str)
            is_on = strcmpi(str, 'on'); 
            if is_on
                setIfChanged(app.MeasButton, 'Text', 'Stop');
            else 
                setIfChanged(app.MeasButton, 'Text', 'Start');
            end
        end
    
        % Convert input string to time value
        function t = tFromStr(app, str)
            
            % Timestamps can be datetime or numeric
            if isa(app.Lg.Record.timestamps,'datetime')
                try
                    t = datetime(str, 'Format', app.Lg.Record.datetime_fmt);
                catch ME
                    warning(['Failed to convert sting ''%s'' to datetime.\n' ...
                        'Error: ' ME.message], str);
                    t = [];
                end
            else
                try 
                    t = str2double(str);
                catch ME
                    warning(['Failed to convert sting to double..\n' ...
                        'Error: ' ME.message']);
                    t = [];
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Logger, varargin)
            p = inputParser();
            addParameter(p,'name', 'Logger', @ischar);
            addParameter(p,'dummy_mode', false, @islogical);
            parse(p, varargin{:});
            
            app.Lg = Logger;
            
            app.GuiSync = MyGuiSync(app, ...
                'KernelObj',            Logger, ...
                'createCallbackFcn',    @app.publicCreateCallbackFcn, ...
                'updateGuiFcn',         @app.updateGui);
            
            app.LoggerUIFigure.Name = p.Results.name;
            
            L = addlistener(app.Lg, 'NewData', @app.newDataCallback);
            addToCleanup(app.GuiSync, L);
            
            if p.Results.dummy_mode
                disp('Logger will not be able to acquire measurements in dummy mode.')
                
                % Disable the measurement panel in dummy mode
                Elems = app.MeasurementPanel.Children;
                for i=1:length(Elems)
                    Elems(i).Enable = 'off';
                end
            end
            
            % Store the initial sizes of components for resizing
            app.axes_init_pos = app.Axes.OuterPosition;
            app.init_panel_h = app.LoggerUIFigure.OuterPosition(4)-app.Axes.OuterPosition(4);
            
            % Add an extended menu to the axes
            axtoolbar(app.Axes, {'export', 'datacursor', 'zoomin', 'zoomout', 'restoreview'});
            
            createLinks(app);
            updateDataDisp(app);
            updateGui(app);
        end

        % Value changed function: MeasButton
        function MeasButtonValueChanged(app, event)
            value = app.MeasButton.Value;
            setMeasButton(app, value);
            if value
                start(app.Lg);
                app.MeasperiodsEditField.Editable = 'off';
            else
                stop(app.Lg);
                app.MeasperiodsEditField.Editable = 'on';
            end
            updateGui(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clear(app.Lg.Record);
            updateGui(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            save(app.Lg.Record);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addTimeLabel(app.Lg.Record, 'save', app.SavecontCheckBox.Value);
            updateTimeLabelDropDown(app);
            updateDataDisp(app);
        end

        % Button pushed function: DeleteTlButton
        function DeleteTlButtonPushed(app, event)
            
            % Delete existing label
            if isempty(app.SelectedTlDropDown.Items)
                return
            end
            
            ind = app.SelectedTlDropDown.Value;
            app.Lg.Record.TimeLabels(ind) = [];
            
            updateGui(app);
            
            if app.SavecontCheckBox.Value
                
                % Save metadata with new time labels
                save(app.Lg.Record.Metadata, app.Lg.Record.meta_file_name, ...
                    'overwrite', true);
            end
        end

        % Value changed function: DispTraceCheckBox_1, 
        % DispTraceCheckBox_10, DispTraceCheckBox_2, 
        % DispTraceCheckBox_3, DispTraceCheckBox_4, 
        % DispTraceCheckBox_5, DispTraceCheckBox_6, 
        % DispTraceCheckBox_7, DispTraceCheckBox_8, 
        % DispTraceCheckBox_9
        function DispTraceValueChanged(app, event)
            ind = sscanf(event.Source.Tag,'%i');
            app.Pl(ind).Visible = event.Source.Value;
            updateDataDisp(app);
        end

        % Value changed function: yminEditField
        function yminEditFieldValueChanged(app, event)
            app.Axes.YLim(1) = app.yminEditField.Value;
            updateDataDisp(app);
        end

        % Value changed function: ymaxEditField
        function ymaxEditFieldValueChanged(app, event)
            app.Axes.YLim(2) = app.ymaxEditField.Value;
            updateDataDisp(app);
        end

        % Value changed function: tminEditField
        function tminEditFieldValueChanged(app, event)
            str = app.tminEditField.Value;
            t = tFromStr(app, str);
            if ~isempty(t)
                app.Axes.XLim(1) = t;
            else
                warning('Limit value is unchanged.');
                app.tminEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            str = app.tmaxEditField.Value;
            t = tFromStr(app, str);
            if ~isempty(t)
                app.Axes.XLim(2) = t;
            else
                warning('Limit value is unchanged.');
                app.tmaxEditField.Value = event.PreviousValue;
            end
        end

        % Button pushed function: ModifyTlButton
        function ModifyTlButtonPushed(app, event)
            
            % Modify text of an existing label
            if isempty(app.SelectedTlDropDown.Items)
                return
            end
            
            ind = app.SelectedTlDropDown.Value;
            modifyTimeLabel(app.Lg.Record, ind, 'save', app.SavecontCheckBox.Value);
            
            updateGui(app);
        end

        % Value changed function: DisplayTlCheckBox
        function DisplayTlCheckBoxValueChanged(app, event)
            if app.DisplayTlCheckBox.Value
                
                % Plot time labels by updating dispaly
                updateDataDisp(app);
            else
                
                % Erase existing time labels
                eraseTimeLabels(app.Lg.Record, app.Axes);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try 
                dir = getLocalSettings('measurement_base_dir');
            catch
                dir = '';
            end
            
            [fname, path] = uigetfile([dir,'\*']);
            
            if ~isequal(fname,0) && ~isequal(path,0)
                app.Lg.Record = MyLog(fullfile(path,fname));
            end
            
            % Bring gui to the front, as uidialog has pushed it to background
            app.LoggerUIFigure.Visible = 'off';
            app.LoggerUIFigure.Visible = 'on';
            
            updateGui(app);
        end

        % Size changed function: LoggerUIFigure
        function LoggerUIFigureSizeChanged(app, event)
            position = app.LoggerUIFigure.Position;
            
            if isempty(app.axes_init_pos)
                return
            end
            
            % Resize axes
            w = position(3);
            h = position(4)-app.init_panel_h;
            app.Axes.OuterPosition = [app.axes_init_pos(1:2), w, h];
            app.Axes.PlotBoxAspectRatio = [w, h, h];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoggerUIFigure = uifigure('Visible', 'off');
            app.LoggerUIFigure.Name = 'Logger';
            app.LoggerUIFigure.SizeChangedFcn = createCallbackFcn(app, @LoggerUIFigureSizeChanged, true);
            app.DisplaytracesMenu = uimenu(app.LoggerUIFigure);
            app.DisplaytracesMenu.Text = 'Display traces';
            app.Menu_2 = uimenu(app.DisplaytracesMenu);
            app.Menu_2.Text = 'Menu';
            app.AxesMenu = uimenu(app.LoggerUIFigure);
            app.AxesMenu.Text = 'Axes';
            app.Menu_3 = uimenu(app.AxesMenu);
            app.Menu_3.Text = 'Menu';
            app.Axes = uiaxes(app.LoggerUIFigure);
            xlabel(app.Axes, 'Time')
            app.FileEditFieldLabel = uilabel(app.LoggerUIFigure);
            app.FileEditFieldLabel.Text = ' File';
            app.FileEditField = uieditfield(app.LoggerUIFigure, 'text');
            app.FileEditField.Value = 'M:\Measurement Campaigns\2018-07-13 Pressure Log';
            app.LegendArea = uitextarea(app.LoggerUIFigure);
            app.TimelabelsPanel = uipanel(app.LoggerUIFigure);
            app.TimelabelsPanel.Title = 'Time labels';
            app.AddButton = uibutton(app.TimelabelsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SelectedDropDownLabel = uilabel(app.TimelabelsPanel);
            app.SelectedDropDownLabel.Text = 'Selected';
            app.SelectedTlDropDown = uidropdown(app.TimelabelsPanel);
            app.SelectedTlDropDown.Items = {'24-Dec-2018 12:45:00 dummy'};
            app.SelectedTlDropDown.Value = '24-Dec-2018 12:45:00 dummy';
            app.DeleteTlButton = uibutton(app.TimelabelsPanel, 'push');
            app.DeleteTlButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTlButtonPushed, true);
            app.DeleteTlButton.Text = 'Delete';
            app.ModifyTlButton = uibutton(app.TimelabelsPanel, 'push');
            app.ModifyTlButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyTlButtonPushed, true);
            app.ModifyTlButton.Text = 'Modify';
            app.DisplayTlCheckBox = uicheckbox(app.TimelabelsPanel);
            app.DisplayTlCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayTlCheckBoxValueChanged, true);
            app.DisplayTlCheckBox.Text = 'Display';
            app.DisplayTlCheckBox.Value = true;
            app.MeasurementPanel = uipanel(app.LoggerUIFigure);
            app.MeasurementPanel.Title = 'Measurement';
            app.MeasButton = uibutton(app.MeasurementPanel, 'state');
            app.MeasButton.ValueChangedFcn = createCallbackFcn(app, @MeasButtonValueChanged, true);
            app.MeasButton.Text = 'Start';
            app.IntervalsLabel = uilabel(app.MeasurementPanel);
            app.IntervalsLabel.Text = 'Interval (s)';
            app.MeasperiodsEditField = uieditfield(app.MeasurementPanel, 'numeric');
            app.SavecontCheckBox = uicheckbox(app.MeasurementPanel);
            app.SavecontCheckBox.Text = 'Save continuously';
            app.PlotlimitsPanel = uipanel(app.LoggerUIFigure);
            app.PlotlimitsPanel.Title = 'Plot limits';
            app.tminEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.tminEditFieldLabel.Text = 't min';
            app.tminEditField = uieditfield(app.PlotlimitsPanel, 'text');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @tminEditFieldValueChanged, true);
            app.tminEditField.Value = '23-Dec-2018 15:59:08';
            app.tmaxEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.tmaxEditFieldLabel.Text = 't max';
            app.tmaxEditField = uieditfield(app.PlotlimitsPanel, 'text');
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = '23-Dec-2018 15:59:08';
            app.yminEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotlimitsPanel, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @yminEditFieldValueChanged, true);
            app.ymaxEditFieldLabel = uilabel(app.PlotlimitsPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotlimitsPanel, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @ymaxEditFieldValueChanged, true);
            app.DisplaytracesPanel = uipanel(app.LoggerUIFigure);
            app.DisplaytracesPanel.Title = 'Display traces';
            app.DispTraceCheckBox_1 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_1.Text = '1';
            app.DispTraceCheckBox_2 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_2.Text = '2';
            app.DispTraceCheckBox_3 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_3.Text = '3';
            app.DispTraceCheckBox_4 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_4.Text = '4';
            app.DispTraceCheckBox_5 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_5.Text = '5';
            app.DispTraceCheckBox_6 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_6.Text = '6';
            app.DispTraceCheckBox_7 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_7.Text = '7';
            app.DispTraceCheckBox_8 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_8.Text = '8';
            app.DispTraceCheckBox_9 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_9.Text = '9';
            app.DispTraceCheckBox_10 = uicheckbox(app.DisplaytracesPanel);
            app.DispTraceCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @DispTraceValueChanged, true);
            app.DispTraceCheckBox_10.Text = '10';
            app.YscaleDropDownLabel = uilabel(app.LoggerUIFigure);
            app.YscaleDropDownLabel.Text = 'Y scale';
            app.YscaleDropDown = uidropdown(app.LoggerUIFigure);
            app.YscaleDropDown.Items = {'linear', 'log'};
            app.YscaleDropDown.Value = 'linear';
            app.RecordPanel = uipanel(app.LoggerUIFigure);
            app.RecordPanel.Title = 'Record';
            app.LoadButton = uibutton(app.RecordPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ClearButton = uibutton(app.RecordPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveButton = uibutton(app.RecordPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LimitmodeLabel = uilabel(app.LoggerUIFigure);
            app.LimitmodeLabel.Text = 'Limit mode';
            app.XLimModeDropDown = uidropdown(app.LoggerUIFigure);
            app.XLimModeDropDown.Items = {'auto', 'manual'};
            app.XLimModeDropDown.Value = 'auto';
            app.LimitmodeLabel_2 = uilabel(app.LoggerUIFigure);
            app.LimitmodeLabel_2.Text = 'Limit mode';
            app.YLimModeDropDown = uidropdown(app.LoggerUIFigure);
            app.YLimModeDropDown.Items = {'auto', 'manual'};
            app.YLimModeDropDown.Value = 'manual';
            app.AutoscaleYCheckBox = uicheckbox(app.LoggerUIFigure);
            app.AutoscaleYCheckBox.Text = 'Autoscale Y';
            app.AutoscaleXCheckBox = uicheckbox(app.LoggerUIFigure);
            app.AutoscaleXCheckBox.Text = 'Autoscale X';
            % Show the figure after all components are created
            app.LoggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiLogger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoggerUIFigure)
        end
    end
end