classdef GuiNa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkAnalyzerUIFigure       matlab.ui.Figure
        ReinitializeButton            matlab.ui.control.Button
        SinglecaptureButton           matlab.ui.control.Button
        StartfreqMHzEditFieldLabel    matlab.ui.control.Label
        StartfreqMHzEditField         matlab.ui.control.NumericEditField
        StopfreqMHzEditFieldLabel     matlab.ui.control.Label
        StopfreqMHzEditField          matlab.ui.control.NumericEditField
        IFBWHzEditFieldLabel          matlab.ui.control.Label
        IFBWHzEditField               matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel  matlab.ui.control.Label
        NumberofpointsEditField       matlab.ui.control.NumericEditField
        ProbepowerdBEditFieldLabel    matlab.ui.control.Label
        ProbepowerdBEditField         matlab.ui.control.NumericEditField
        SweeptypeDropDownLabel        matlab.ui.control.Label
        SweeptypeDropDown             matlab.ui.control.DropDown
        CenterfreqMHzEditFieldLabel   matlab.ui.control.Label
        CenterfreqMHzEditField        matlab.ui.control.NumericEditField
        SpanMHzEditFieldLabel         matlab.ui.control.Label
        SpanMHzEditField              matlab.ui.control.NumericEditField
        MeasPar1DropDown              matlab.ui.control.DropDown
        NtracesDropDownLabel          matlab.ui.control.Label
        NtracesDropDown               matlab.ui.control.DropDown
        Trace1Label                   matlab.ui.control.Label
        Trace1FormatDropDown          matlab.ui.control.DropDown
        Trace2Label                   matlab.ui.control.Label
        Trace2FormatDropDown          matlab.ui.control.DropDown
        NaveragesEditFieldLabel       matlab.ui.control.Label
        NaveragesEditField            matlab.ui.control.NumericEditField
        OutputButton                  matlab.ui.control.StateButton
        Trace1Axes                    matlab.ui.control.UIAxes
        Trace2Axes                    matlab.ui.control.UIAxes
        MeasPar2DropDown              matlab.ui.control.DropDown
        ParameterLabel                matlab.ui.control.Label
        DataformatLabel               matlab.ui.control.Label
        TriggerButtonGroup            matlab.ui.container.ButtonGroup
        HoldButton                    matlab.ui.control.RadioButton
        SingleButton                  matlab.ui.control.RadioButton
        ContinuousButton              matlab.ui.control.RadioButton
    end


    properties (Access = public)
        Instr; % MyNa class object
        linkedElementsList = {} % list of Gui control elements 
        % which have counterpart properties
    end

    methods (Access = private)
        
        function updateTraceCtrlAccess(app)
            if app.Instr.trace_no > 1
                app.Trace2FormatDropDown.Enable = 'on';
                app.Trace2Label.Enable = 'on';
                app.MeasPar2DropDown.Enable = 'on';
            else 
                app.Trace2FormatDropDown.Enable = 'off';
                app.Trace2Label.Enable = 'off';
                app.MeasPar2DropDown.Enable = 'off';
            end
        end
    
    end

    methods (Access = public)
        
        function linkControlElement(app, elem, prop_tag, varargin)
            p=inputParser;
            addRequired(p,'elem');
            addRequired(p,'prop_tag',@ischar);
            addParameter(p,'input_presc',1,@isnumeric);
            addParameter(p,'auto_callback',false, @islogical); 
            parse(p,elem,prop_tag,varargin{:});
            
            % The property-control link is established by assigning the tag
            % and adding the control to the list of linked elements
            elem.Tag = prop_tag;
            app.linkedElementsList = [app.linkedElementsList, elem];
            
            % If the auto callback flag is set, assign the default 
            % ValueChangedFcn which passes the field input to the instument 
            if p.Results.auto_callback
                elem.ValueChangedFcn = createCallbackFcn(app,...
                    @(src, event)genericValueChanged(src, event), true);
            end
            
            % If the prescaler is indicated, add it to the element as a new property
            if p.Results.input_presc ~= 1
                if isprop(elem, 'InputPrescaler')
                    warning('The InputPrescaler propety already exists in the control element');
                else
                    addprop(elem,'InputPrescaler');
                end
                elem.InputPrescaler = p.Results.input_presc;
            end
        end
        
        % update all the linked control elements according to their counterpart properties
        function updateGui(app)
            for i=1:length(app.linkedElementsList)
                tmpelem = app.linkedElementsList(i);
                tmpval = app.Instr.(tmpelem.Tag);
                % scale the value if the control element has a prescaler
                if isprop(tmpelem, 'InputPrescaler')
                    tmpval = tmpval*tmpelem.InputPrescaler;
                end
                tmpelem.Value = tmpval;
            end
        end 
        
        function genericValueChanged(app, event)
            val = event.Value;
            % scale the value if the control element has a prescaler
            if isprop(event.Source, 'InputPrescaler')
                val = val/event.Source.InputPrescaler;
            end
            app.Instr.writePropertyHedged(event.Source.Tag, val);
            app.updateGui();
        end
        
        function createControlLinks(app)
            linkControlElement(app, ...
                app.StartfreqMHzEditField, 'start_freq', ...
                'input_presc',1e-6, 'auto_callback',true);
            linkControlElement(app, ...
                app.CenterfreqMHzEditField, 'cent_freq', ...
                'input_presc',1e-6, 'auto_callback',true);
            linkControlElement(app, ...
                app.StopfreqMHzEditField, 'stop_freq', ...
                'input_presc',1e-6, 'auto_callback',true);
            linkControlElement(app, ...
                app.SpanMHzEditField, 'span',...
                'input_presc',1e-6, 'auto_callback',true);
            linkControlElement(app, ...
                app.IFBWHzEditField, 'ifbw',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.NaveragesEditField, 'average_no',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.NumberofpointsEditField, 'point_no',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.MeasPar1DropDown, 'meas_par1',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.MeasPar2DropDown, 'meas_par2',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.SweeptypeDropDown, 'sweep_type',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.ProbepowerdBEditField, 'power',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.OutputButton, 'enable_out',...
                'auto_callback',true);
            linkControlElement(app, ...
                app.NtracesDropDown, 'trace_no');
            linkControlElement(app, ...
                app.Trace1FormatDropDown, 'form1');
            linkControlElement(app, ...
                app.Trace2FormatDropDown, 'form2');
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Instr = MyNa('TCPIP','192.168.1.4');
            
            % to make a numerical dropdown
            app.NtracesDropDown.ItemsData = str2double(app.NtracesDropDown.ItemsData);
            
            app.createControlLinks();
            app.Instr.readPropertyHedged('all');
            app.updateGui();
            app.updateTraceCtrlAccess();
        end

        % Close request function: NetworkAnalyzerUIFigure
        function NetworkAnalyzerUIFigureCloseRequest(app, event)
            delete(app.Instr);
            delete(app);
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            app.Instr.readPropertyHedged('all');
        end

        % Button pushed function: SinglecaptureButton
        function SinglecaptureButtonPushed(app, event)
            app.Instr.openDevice();
            app.Instr.readTrace(1);
            plot(app.Trace1Axes,app.Instr.data1.x,app.Instr.data1.y);
            if app.Instr.trace_no > 1
                app.Instr.readTrace(2);
                plot(app.Trace2Axes,app.Instr.data2.x,app.Instr.data2.y);
            end
            app.Instr.closeDevice();
        end

        % Value changed function: NtracesDropDown
        function NtracesDropDownValueChanged(app, event)
            value = int32(app.NtracesDropDown.Value);
            
            if value==1 || value==2
                app.Instr.writePropertyHedged('trace_no', value);
                app.updateTraceCtrlAccess();
                
            else
                error('Unknown dropdown option');
            end
            app.updateGui();
        end

        % Selection changed function: TriggerButtonGroup
        function TriggerButtonGroupSelectionChanged(app, event)
            selectedButton = app.TriggerButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Hold'
                    app.Instr.abortSweep();
                case 'Single'
                    app.Instr.singleSweep();
                    app.HoldButton.Value = true;
                case 'Continuous'
                    app.Instr.startContSweep();
                otherwise
                    error('Trigger case unknown in callback');
            end
        end

        % Value changed function: Trace1FormatDropDown
        function Trace1FormatDropDownValueChanged(app, event)
            value = app.Trace1FormatDropDown.Value;
            app.Instr.openDevice();
            app.Instr.writeTraceFormat(1, value);
            app.Instr.readProperty('all');
            app.Instr.closeDevice();
            app.updateGui();
        end

        % Value changed function: Trace2FormatDropDown
        function Trace2FormatDropDownValueChanged(app, event)
            value = app.Trace2FormatDropDown.Value;
            app.Instr.openDevice();
            app.Instr.writeTraceFormat(2, value);
            app.Instr.readProperty('all');
            app.Instr.closeDevice();
            app.updateGui();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkAnalyzerUIFigure = uifigure;
            app.NetworkAnalyzerUIFigure.Name = 'Network Analyzer';
            app.NetworkAnalyzerUIFigure.CloseRequestFcn = createCallbackFcn(app, @NetworkAnalyzerUIFigureCloseRequest, true);
            app.ReinitializeButton = uibutton(app.NetworkAnalyzerUIFigure, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Reinitialize';
            app.SinglecaptureButton = uibutton(app.NetworkAnalyzerUIFigure, 'push');
            app.SinglecaptureButton.ButtonPushedFcn = createCallbackFcn(app, @SinglecaptureButtonPushed, true);
            app.SinglecaptureButton.Text = 'Single capture';
            app.StartfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.StartfreqMHzEditFieldLabel.Text = 'Start freq (MHz)';
            app.StartfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.StartfreqMHzEditField.Limits = [0 3000];
            app.StopfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.StopfreqMHzEditFieldLabel.Text = 'Stop freq (MHz)';
            app.StopfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.IFBWHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.IFBWHzEditFieldLabel.Text = 'IF BW (Hz)';
            app.IFBWHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.IFBWHzEditField.ValueDisplayFormat = '%e';
            app.NumberofpointsEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.ProbepowerdBEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.ProbepowerdBEditFieldLabel.Text = 'Probe power (dB)';
            app.ProbepowerdBEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.SweeptypeDropDownLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.SweeptypeDropDownLabel.Text = 'Sweep type';
            app.SweeptypeDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.SweeptypeDropDown.Items = {'Linear', 'Log'};
            app.SweeptypeDropDown.ItemsData = {'LIN', 'LOG'};
            app.SweeptypeDropDown.Value = 'LIN';
            app.CenterfreqMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.CenterfreqMHzEditFieldLabel.Text = 'Center freq (MHz)';
            app.CenterfreqMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.SpanMHzEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.SpanMHzEditFieldLabel.Text = 'Span (MHz)';
            app.SpanMHzEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.MeasPar1DropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.MeasPar1DropDown.Items = {'S11', 'S12', 'S21', 'S22'};
            app.MeasPar1DropDown.Value = 'S21';
            app.NtracesDropDownLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NtracesDropDownLabel.Text = 'N traces';
            app.NtracesDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.NtracesDropDown.Items = {'1', '2'};
            app.NtracesDropDown.ItemsData = {'1', '2'};
            app.NtracesDropDown.ValueChangedFcn = createCallbackFcn(app, @NtracesDropDownValueChanged, true);
            app.NtracesDropDown.Value = '1';
            app.Trace1Label = uilabel(app.NetworkAnalyzerUIFigure);
            app.Trace1Label.Text = 'Trace 1';
            app.Trace1FormatDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.Trace1FormatDropDown.Items = {'Log magnitude r', 'Lin magnitude r', 'Lin phase'};
            app.Trace1FormatDropDown.ItemsData = {'MLOG', 'MLIN', 'PHAS'};
            app.Trace1FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @Trace1FormatDropDownValueChanged, true);
            app.Trace1FormatDropDown.Value = 'MLOG';
            app.Trace2Label = uilabel(app.NetworkAnalyzerUIFigure);
            app.Trace2Label.Enable = 'off';
            app.Trace2Label.Text = 'Trace 2';
            app.Trace2FormatDropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.Trace2FormatDropDown.Items = {'Log magnitude r', 'Lin magnitude r', 'Lin phase'};
            app.Trace2FormatDropDown.ItemsData = {'MLOG', 'MLIN', 'PHAS'};
            app.Trace2FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @Trace2FormatDropDownValueChanged, true);
            app.Trace2FormatDropDown.Enable = 'off';
            app.Trace2FormatDropDown.Value = 'MLOG';
            app.NaveragesEditFieldLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.NaveragesEditFieldLabel.Text = 'N averages';
            app.NaveragesEditField = uieditfield(app.NetworkAnalyzerUIFigure, 'numeric');
            app.OutputButton = uibutton(app.NetworkAnalyzerUIFigure, 'state');
            app.OutputButton.Text = 'Output';
            app.Trace1Axes = uiaxes(app.NetworkAnalyzerUIFigure);
            title(app.Trace1Axes, 'Trace 1')
            xlabel(app.Trace1Axes, 'Frequency [Hz]')
            ylabel(app.Trace1Axes, 'Y')
            app.Trace1Axes.XLim = [0 1];
            app.Trace1Axes.YLim = [0 1];
            app.Trace1Axes.ZLim = [0 1];
            app.Trace1Axes.CLim = [0 1];
            app.Trace1Axes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Trace1Axes.YTick = [0 0.5 1];
            app.Trace1Axes.ZTick = [0 0.5 1];
            app.Trace2Axes = uiaxes(app.NetworkAnalyzerUIFigure);
            title(app.Trace2Axes, 'Trace 2')
            xlabel(app.Trace2Axes, 'Frequency [Hz]')
            ylabel(app.Trace2Axes, 'Y')
            app.MeasPar2DropDown = uidropdown(app.NetworkAnalyzerUIFigure);
            app.MeasPar2DropDown.Items = {'S11', 'S12', 'S21', 'S22'};
            app.MeasPar2DropDown.Enable = 'off';
            app.MeasPar2DropDown.Value = 'S21';
            app.ParameterLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.ParameterLabel.Text = 'Parameter';
            app.DataformatLabel = uilabel(app.NetworkAnalyzerUIFigure);
            app.DataformatLabel.Text = 'Data format';
            app.TriggerButtonGroup = uibuttongroup(app.NetworkAnalyzerUIFigure);
            app.TriggerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TriggerButtonGroupSelectionChanged, true);
            app.TriggerButtonGroup.Title = 'Trigger';
            app.HoldButton = uiradiobutton(app.TriggerButtonGroup);
            app.HoldButton.Text = 'Hold';
            app.HoldButton.Value = true;
            app.SingleButton = uiradiobutton(app.TriggerButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.TriggerButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiNa

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkAnalyzerUIFigure)
        end
    end
end