classdef GuiScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TektronixDPOUIFigure         matlab.ui.Figure
        CapturetraceButton           matlab.ui.control.Button
        UpdatepanelButton            matlab.ui.control.Button
        ContinuouscaptureCheckBox    matlab.ui.control.CheckBox
        ChannelDropDownLabel         matlab.ui.control.Label
        ChannelDropDown              matlab.ui.control.DropDown
        FindinstrumentButton         matlab.ui.control.Button
        AquisitionButtonGroup        matlab.ui.container.ButtonGroup
        StopButton                   matlab.ui.control.RadioButton
        SingleButton                 matlab.ui.control.RadioButton
        ContinuousButton             matlab.ui.control.RadioButton
        ChannelconfigurationPanel    matlab.ui.container.Panel
        CouplingLabel                matlab.ui.control.Label
        ImpedanceLabel               matlab.ui.control.Label
        CH1Label                     matlab.ui.control.Label
        CH2Label                     matlab.ui.control.Label
        CH3Label                     matlab.ui.control.Label
        CH4Label                     matlab.ui.control.Label
        OffsetVLabel                 matlab.ui.control.Label
        ScaleVDivLabel               matlab.ui.control.Label
        CouplingCH1                  matlab.ui.control.DropDown
        ImpedanceCH1                 matlab.ui.control.DropDown
        OffsetCH1                    matlab.ui.control.NumericEditField
        ScaleCH1                     matlab.ui.control.NumericEditField
        TriggerPanel                 matlab.ui.container.Panel
        ModeDropDownLabel            matlab.ui.control.Label
        ModeDropDown                 matlab.ui.control.DropDown
        SourceDropDownLabel          matlab.ui.control.Label
        SourceDropDown               matlab.ui.control.DropDown
        EdgeDropDownLabel            matlab.ui.control.Label
        EdgeDropDown                 matlab.ui.control.DropDown
        LevelVEditFieldLabel         matlab.ui.control.Label
        LevelVEditField              matlab.ui.control.NumericEditField
        TimescalesDivEditFieldLabel  matlab.ui.control.Label
        TimescalesDivEditField       matlab.ui.control.NumericEditField
        NumberofpointsDropDownLabel  matlab.ui.control.Label
        NumberofpointsDropDown       matlab.ui.control.DropDown
        TraceAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Instr % Instrument object
        linked_elem_list={};
    end

    methods (Access = private)
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        function createControlLinks(app)
            linkControlElement(app, ...
                app.ChannelDropDown, 'channel');
            linkControlElement(app, ...
                app.NumberofpointsDropDown, 'point_no', ...
                'create_callback_fcn', @app.createGenericCallback);
            
            linkControlElement(app,...
                app.TimescalesDivEditField, 'time_scale', ...
                'create_callback_fcn', @app.createGenericCallback);
            
            % trigger panel
            linkControlElement(app, app.SourceDropDown, 'trig_source', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ModeDropDown, 'trig_mode', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.EdgeDropDown, 'trig_slope', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.LevelVEditField, 'trig_lev', ...
                'create_callback_fcn', @app.createGenericCallback);
            
            % channel panel
            linkControlElement(app, app.CouplingCH1, 'cpl1', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ImpedanceCH1, 'imp1', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.ScaleCH1, 'scale1', ...
                'create_callback_fcn', @app.createGenericCallback);
            linkControlElement(app, app.OffsetCH1, 'offset1', ...
                'create_callback_fcn', @app.createGenericCallback);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, interface, address, varargin)
            app.Instr = MyScope(interface, address, varargin{:});
            
            % initialization of dropdown menus
            app.ChannelDropDown.ItemsData = str2double(app.ChannelDropDown.Items);
            app.NumberofpointsDropDown.Items=...
                cellfun(@num2str, app.Instr.CommandList.point_no.val_list,...
                'UniformOutput',false);
            app.NumberofpointsDropDown.ItemsData=...
                app.Instr.CommandList.point_no.val_list;
            
            if app.Instr.acq_state
                app.ContinuousButton.Value = true;
            else
                app.StopButton.Value = true;
            end
                
            createControlLinks(app);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: CapturetraceButton
        function CapturetraceButtonPushed(app, event)
            openDevice(app.Instr);
            readTrace(app.Instr);
            plotTrace(app.Instr.Trace, app.TraceAxes);
            ylabel(app.TraceAxes, app.Instr.Trace.label_y);
            xlabel(app.TraceAxes, app.Instr.Trace.label_x);
            closeDevice(app.Instr);
        end

        % Button pushed function: UpdatepanelButton
        function UpdatepanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Value changed function: ContinuouscaptureCheckBox
        function ContinuouscaptureCheckBoxValueChanged(app, event)
            openDevice(app.Instr);
            while app.ContinuouscaptureCheckBox.Value
                readTrace(app.Instr);
                plotTrace(app.Instr.Trace, app.TraceAxes);
                ylabel(app.TraceAxes, app.Instr.Trace.label_y);
                xlabel(app.TraceAxes, app.Instr.Trace.label_x);
                % somehow a pause between aquisition is needed
                pause(0.5);
            end
            closeDevice(app.Instr);
        end

        % Close request function: TektronixDPOUIFigure
        function TektronixDPOUIFigureCloseRequest(app, event)
            delete(app.Instr);
            delete(app);
        end

        % Button pushed function: FindinstrumentButton
        function FindinstrumentButtonPushed(app, event)
            findInstrumentCallback(app);
        end

        % Selection changed function: AquisitionButtonGroup
        function AquisitionButtonGroupSelectionChanged(app, event)
            selectedButton = app.AquisitionButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Stop'
                    writePropertyHedged(app.Instr, 'acq_state', false);
                case 'Single'
                    acquireSingle(app.Instr);
                    app.StopButton.Value = true;
                case 'Continuous'
                    acquireContinuous(app.Instr);
                otherwise
                    error('Trigger case unknown in callback');
            end
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TektronixDPOUIFigure = uifigure;
            app.TektronixDPOUIFigure.Name = 'Tektronix DPO';
            app.TektronixDPOUIFigure.CloseRequestFcn = createCallbackFcn(app, @TektronixDPOUIFigureCloseRequest, true);
            app.CapturetraceButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.CapturetraceButton.ButtonPushedFcn = createCallbackFcn(app, @CapturetraceButtonPushed, true);
            app.CapturetraceButton.Text = 'Capture trace';
            app.UpdatepanelButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.UpdatepanelButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatepanelButtonPushed, true);
            app.UpdatepanelButton.Text = 'Update panel';
            app.ContinuouscaptureCheckBox = uicheckbox(app.TektronixDPOUIFigure);
            app.ContinuouscaptureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuouscaptureCheckBoxValueChanged, true);
            app.ContinuouscaptureCheckBox.Text = 'Continuous capture';
            app.ChannelDropDownLabel = uilabel(app.TektronixDPOUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.TektronixDPOUIFigure);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.Value = '1';
            app.FindinstrumentButton = uibutton(app.TektronixDPOUIFigure, 'push');
            app.FindinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @FindinstrumentButtonPushed, true);
            app.FindinstrumentButton.Text = 'Find instrument';
            app.AquisitionButtonGroup = uibuttongroup(app.TektronixDPOUIFigure);
            app.AquisitionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AquisitionButtonGroupSelectionChanged, true);
            app.AquisitionButtonGroup.Title = 'Aquisition';
            app.StopButton = uiradiobutton(app.AquisitionButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.SingleButton = uiradiobutton(app.AquisitionButtonGroup);
            app.SingleButton.Text = 'Single';
            app.ContinuousButton = uiradiobutton(app.AquisitionButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ChannelconfigurationPanel = uipanel(app.TektronixDPOUIFigure);
            app.ChannelconfigurationPanel.Title = 'Channel configuration';
            app.CouplingLabel = uilabel(app.ChannelconfigurationPanel);
            app.CouplingLabel.Text = 'Coupling';
            app.ImpedanceLabel = uilabel(app.ChannelconfigurationPanel);
            app.ImpedanceLabel.Text = 'Impedance';
            app.CH1Label = uilabel(app.ChannelconfigurationPanel);
            app.CH1Label.Text = 'CH 1';
            app.CH2Label = uilabel(app.ChannelconfigurationPanel);
            app.CH2Label.Text = 'CH 2';
            app.CH3Label = uilabel(app.ChannelconfigurationPanel);
            app.CH3Label.Text = 'CH 3';
            app.CH4Label = uilabel(app.ChannelconfigurationPanel);
            app.CH4Label.Text = 'CH 4';
            app.OffsetVLabel = uilabel(app.ChannelconfigurationPanel);
            app.OffsetVLabel.Text = 'Offset [V]';
            app.ScaleVDivLabel = uilabel(app.ChannelconfigurationPanel);
            app.ScaleVDivLabel.Text = 'Scale [V/Div]';
            app.CouplingCH1 = uidropdown(app.ChannelconfigurationPanel);
            app.CouplingCH1.Items = {'DC', 'AC', 'GND'};
            app.CouplingCH1.Value = 'DC';
            app.ImpedanceCH1 = uidropdown(app.ChannelconfigurationPanel);
            app.ImpedanceCH1.Items = {'1 Mohm', '50 Ohm'};
            app.ImpedanceCH1.ItemsData = {'MEG', 'FIF'};
            app.ImpedanceCH1.Value = 'MEG';
            app.OffsetCH1 = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.OffsetCH1.ValueDisplayFormat = '%5.2e';
            app.ScaleCH1 = uieditfield(app.ChannelconfigurationPanel, 'numeric');
            app.ScaleCH1.ValueDisplayFormat = '%5.2e';
            app.TriggerPanel = uipanel(app.TektronixDPOUIFigure);
            app.TriggerPanel.Title = 'Trigger';
            app.ModeDropDownLabel = uilabel(app.TriggerPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.TriggerPanel);
            app.ModeDropDown.Items = {'Auto', 'Normal'};
            app.ModeDropDown.ItemsData = {'AUTO', 'NORM'};
            app.ModeDropDown.Value = 'AUTO';
            app.SourceDropDownLabel = uilabel(app.TriggerPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.TriggerPanel);
            app.SourceDropDown.Items = {'CH1', 'CH2', 'CH3', 'CH4', 'AUX', 'EXT', 'LINE'};
            app.SourceDropDown.Value = 'AUX';
            app.EdgeDropDownLabel = uilabel(app.TriggerPanel);
            app.EdgeDropDownLabel.Text = 'Edge';
            app.EdgeDropDown = uidropdown(app.TriggerPanel);
            app.EdgeDropDown.Items = {'Rise', 'Fall'};
            app.EdgeDropDown.ItemsData = {'RIS', 'FALL'};
            app.EdgeDropDown.Value = 'RIS';
            app.LevelVEditFieldLabel = uilabel(app.TriggerPanel);
            app.LevelVEditFieldLabel.Text = 'Level [V]';
            app.LevelVEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.TimescalesDivEditFieldLabel = uilabel(app.TektronixDPOUIFigure);
            app.TimescalesDivEditFieldLabel.Text = 'Time scale [s/Div]';
            app.TimescalesDivEditField = uieditfield(app.TektronixDPOUIFigure, 'numeric');
            app.TimescalesDivEditField.ValueDisplayFormat = '%5.2e';
            app.NumberofpointsDropDownLabel = uilabel(app.TektronixDPOUIFigure);
            app.NumberofpointsDropDownLabel.Text = 'Number of points';
            app.NumberofpointsDropDown = uidropdown(app.TektronixDPOUIFigure);
            app.TraceAxes = uiaxes(app.TektronixDPOUIFigure);
            title(app.TraceAxes, 'Trace')
            xlabel(app.TraceAxes, 'X')
            ylabel(app.TraceAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiScope(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TektronixDPOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TektronixDPOUIFigure)
        end
    end
end