classdef GuiTlb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TemplateUIFigure              matlab.ui.Figure
        RefreshpanelButton            matlab.ui.control.Button
        WavelengthtrackCheckBox       matlab.ui.control.CheckBox
        WavelengthnmLabel             matlab.ui.control.Label
        WavelengthnmEditField         matlab.ui.control.NumericEditField
        PowermWEditFieldLabel         matlab.ui.control.Label
        PowermWEditField              matlab.ui.control.NumericEditField
        DiodecurrentmAEditFieldLabel  matlab.ui.control.Label
        DiodecurrentmAEditField       matlab.ui.control.NumericEditField
        DiodetemperatureCEditFieldLabel  matlab.ui.control.Label
        DiodetemperatureCEditField    matlab.ui.control.NumericEditField
        WavelengthsetpointEditFieldLabel  matlab.ui.control.Label
        WavelengthsetpointEditField   matlab.ui.control.NumericEditField
        WlPlusButton                  matlab.ui.control.Button
        WlMinusButton                 matlab.ui.control.Button
        IncrementEditFieldLabel       matlab.ui.control.Label
        IncrementEditField            matlab.ui.control.NumericEditField
        ConstantpowerCheckBox         matlab.ui.control.CheckBox
        PowersetpointEditFieldLabel   matlab.ui.control.Label
        PowersetpointEditField        matlab.ui.control.NumericEditField
        ActualvalueLabel              matlab.ui.control.Label
        SetpointLabel                 matlab.ui.control.Label
        MaxpowerButton                matlab.ui.control.Button
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = '' ; % App name to clear on exit;
    end

    methods (Access = private)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        % Lineked control elements are updated by calling updateGui(app)
        function createControlLinks(app)
            linkGuiElementToCommand(app, app.element, 'instr_property');
            %....
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            readPropertyHedged(app.Instr,'all');
            
            % initialization of dropdown menus (convert text to numbers if necessary)
            app.DropDown.ItemsData = str2double(app.DropDown.Items);
         
            createControlLinks(app);
            updateGui(app);
        end

        % Button pushed function: RefreshpanelButton
        function RefreshpanelButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Close request function: TemplateUIFigure
        function TemplateUIFigureCloseRequest(app, event)
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TemplateUIFigure = uifigure;
            app.TemplateUIFigure.Name = 'Template';
            app.TemplateUIFigure.CloseRequestFcn = createCallbackFcn(app, @TemplateUIFigureCloseRequest, true);
            app.RefreshpanelButton = uibutton(app.TemplateUIFigure, 'push');
            app.RefreshpanelButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpanelButtonPushed, true);
            app.RefreshpanelButton.Text = 'Refresh panel';
            app.WavelengthtrackCheckBox = uicheckbox(app.TemplateUIFigure);
            app.WavelengthtrackCheckBox.Text = 'Wavelength track';
            app.WavelengthnmLabel = uilabel(app.TemplateUIFigure);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.WavelengthnmEditField.Editable = 'off';
            app.WavelengthnmEditField.Value = 780;
            app.PowermWEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.PowermWEditField.Editable = 'off';
            app.PowermWEditField.Value = 23;
            app.DiodecurrentmAEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.DiodecurrentmAEditFieldLabel.Text = 'Diode current (mA)';
            app.DiodecurrentmAEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.DiodecurrentmAEditField.Editable = 'off';
            app.DiodecurrentmAEditField.Value = 10;
            app.DiodetemperatureCEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.DiodetemperatureCEditFieldLabel.Text = 'Diode temperature (C)';
            app.DiodetemperatureCEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.DiodetemperatureCEditField.Editable = 'off';
            app.WavelengthsetpointEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.WavelengthsetpointEditFieldLabel.Text = 'Wavelength setpoint';
            app.WavelengthsetpointEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.WlPlusButton = uibutton(app.TemplateUIFigure, 'push');
            app.WlPlusButton.Text = '+';
            app.WlMinusButton = uibutton(app.TemplateUIFigure, 'push');
            app.WlMinusButton.Text = '-';
            app.IncrementEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.ConstantpowerCheckBox = uicheckbox(app.TemplateUIFigure);
            app.ConstantpowerCheckBox.Text = 'Constant power';
            app.PowersetpointEditFieldLabel = uilabel(app.TemplateUIFigure);
            app.PowersetpointEditFieldLabel.Text = 'Power setpoint';
            app.PowersetpointEditField = uieditfield(app.TemplateUIFigure, 'numeric');
            app.ActualvalueLabel = uilabel(app.TemplateUIFigure);
            app.ActualvalueLabel.Text = 'Actual value';
            app.SetpointLabel = uilabel(app.TemplateUIFigure);
            app.SetpointLabel.Text = 'Setpoint';
            app.MaxpowerButton = uibutton(app.TemplateUIFigure, 'push');
            app.MaxpowerButton.Text = 'Max power';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiTlb(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TemplateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TemplateUIFigure)
        end
    end
end