classdef GuiTpg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure         matlab.ui.Figure
        GaugePanel1        matlab.ui.container.Panel
        PEditFieldLabel    matlab.ui.control.Label
        PDisp1             matlab.ui.control.EditField
        GaugePanel2        matlab.ui.container.Panel
        PEditField_2Label  matlab.ui.control.Label
        PDisp2             matlab.ui.control.EditField
        LoggerButton       matlab.ui.control.Button
        SinglereadButton   matlab.ui.control.Button
    end


    properties (GetAccess = public, SetAccess = protected)
        GuiSync
        Instr
    end

    methods (Access = public)
        function cb = publicCreateCallbackFcn(app, f)
            cb = createCallbackFcn(app, f, true);
        end
    end

    methods (Access = private)
        function createLinks(app)
            addLink(app.GuiSync, app.PDisp1, 'Instr.pressure(1)', 'outputProcessingFcn', @app.printPressure);
            addLink(app.GuiSync, app.PDisp2, 'Instr.pressure(2)', 'outputProcessingFcn', @app.printPressure);
            
            addLink(app.GuiSync, app.GaugePanel1, 'Instr.gauge_stat{1}', 'elem_prop', 'Title', ...
                'outputProcessingFcn', @app.printStat1);
            addLink(app.GuiSync, app.GaugePanel2, 'Instr.gauge_stat{2}', 'elem_prop', 'Title', ...
                'outputProcessingFcn', @app.printStat2);
        end
        
        function str = printPressure(app, p)
            str = sprintf('%.2e %s', p, app.Instr.pressure_unit);
        end
        
        function str = printStat1(app, stat)
            str = sprintf('Gauge 1: %s, %s', app.Instr.gauge_id{1}, stat);
        end
        
        function str = printStat2(app, stat)
            str = sprintf('Gauge 2: %s, %s', app.Instr.gauge_id{2}, stat);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, ...
                'KernelObj',            Instrument, ...
                'createCallbackFcn',    @app.publicCreateCallbackFcn);
            
            createLinks(app);
        end

        % Button pushed function: SinglereadButton
        function SinglereadCallback(app, event)
            sync(app.Instr);
        end

        % Button pushed function: LoggerButton
        function LoggerButtonPushed(app, event)
%             if (~isempty(app.GuiLg)) && isvalid(app.GuiLg)
%                 % Bring the window of existing logger gui to front
%                 fig = findfigure(app.GuiLg);
%                 fig.Visible = 'off';
%                 fig.Visible = 'on';
%             else
%                 Settings = getLocalSettings();
%                 base_dir = Settings.measurement_base_dir;
%                 session_path = createSessionPath(base_dir, 'Pressure log');
%                 file_name = fullfile(session_path,'Pressure.log');
%                 
%                 % Start new gui, ensuring Instr.Lg is a valid MyLogger object
%                 initLogger(app.Instr, 'file_name', file_name);                
%                 app.GuiLg = GuiLogger(app.Instr.Lg,'name',[app.Instr.name,' Pressure logger']);
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'TPG pressure gauge controller';
            app.GaugePanel1 = uipanel(app.MainFigure);
            app.GaugePanel1.Title = 'Gauge 1';
            app.PEditFieldLabel = uilabel(app.GaugePanel1);
            app.PEditFieldLabel.Text = 'P';
            app.PDisp1 = uieditfield(app.GaugePanel1, 'text');
            app.PDisp1.Editable = 'off';
            app.GaugePanel2 = uipanel(app.MainFigure);
            app.GaugePanel2.Title = 'Gauge 2';
            app.PEditField_2Label = uilabel(app.GaugePanel2);
            app.PEditField_2Label.Text = 'P';
            app.PDisp2 = uieditfield(app.GaugePanel2, 'text');
            app.PDisp2.Editable = 'off';
            app.LoggerButton = uibutton(app.MainFigure, 'push');
            app.LoggerButton.ButtonPushedFcn = createCallbackFcn(app, @LoggerButtonPushed, true);
            app.LoggerButton.Text = 'Logger';
            app.SinglereadButton = uibutton(app.MainFigure, 'push');
            app.SinglereadButton.ButtonPushedFcn = createCallbackFcn(app, @SinglereadCallback, true);
            app.SinglereadButton.Text = 'Single read';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTpg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end