classdef GuiZiScopeFt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiScopeFt                      matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        ScopenumberEditFieldLabel      matlab.ui.control.Label
        ScopenumberEditField           matlab.ui.control.NumericEditField
        SignalinputEditFieldLabel      matlab.ui.control.Label
        SignalinputEditField           matlab.ui.control.NumericEditField
        TracetoDAQButton               matlab.ui.control.Button
        PlotPanel                      matlab.ui.container.Panel
        ylimitmodeLabel                matlab.ui.control.Label
        YLimModeDropDown               matlab.ui.control.DropDown
        yminEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        AcquisitionPanel               matlab.ui.container.Panel
        NumberofpointsEditFieldLabel   matlab.ui.control.Label
        NumberofpointsEditField        matlab.ui.control.NumericEditField
        FFTRBWEditFieldLabel           matlab.ui.control.Label
        FFTRBWEditField                matlab.ui.control.NumericEditField
        SamplingrateptsEditFieldLabel  matlab.ui.control.Label
        SamplingrateptsEditField       matlab.ui.control.NumericEditField
        AveragingPanel                 matlab.ui.container.Panel
        AvgtypeDropDownLabel           matlab.ui.control.Label
        AvgtypeDropDown                matlab.ui.control.DropDown
        navgEditFieldLabel             matlab.ui.control.Label
        navgEditField                  matlab.ui.control.NumericEditField
        CompletedEditFieldLabel        matlab.ui.control.Label
        CompletedEditField             matlab.ui.control.NumericEditField
        RestartButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = ''  % App name to clear on exit
        Listeners = struct()
    end
    
    
    methods (Access = public)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
    
    end
    
    methods (Access = private)
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            linkGuiElement(app, app.ScopenumberEditField, '.Instr.n_scope');
            linkGuiElement(app, app.SignalinputEditField, '.Instr.signal_in');
            linkGuiElement(app, app.AvgtypeDropDown, '.Instr.Trace.avg_type');
            linkGuiElement(app, app.CompletedEditField, '.Instr.Trace.avg_count');
            linkGuiElement(app, app.FFTRBWEditField, '.Instr.fft_rbw');
            linkGuiElement(app, app.SamplingrateptsEditField, '.Instr.scope_rate');
            linkGuiElement(app, app.NumberofpointsEditField, '.Instr.n_pt');
            
            % Axes limits
            linkGuiElement(app, app.yminEditField, '.DemodUIAxes.YLim(1)');
            linkGuiElement(app, app.ymaxEditField, '.DemodUIAxes.YLim(2)');
            linkGuiElement(app, app.YLimModeDropDown, '.DemodUIAxes.YLimMode');
        end
    
        function updatePlot(app)
            % Plot the trace using its class method
            plot(app.Instr.Trace, app.UIAxes);
            % Set x limits manually to beautify dynamic updating
            fmax=app.Instr.scope_rate/2;
            app.UIAxes.XLim=[-fmax, fmax];
            
            % Update the plot-related controls
            updateLinkedElement(app, app.yminEditField);
            updateLinkedElement(app, app.ymaxEditField);
        end
        
        function updateGui(app)
            updateLinkedGuiElements(app)
            updatePlot(app)
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Instr = Instrument;
            createLinks(app);
            
            app.Listeners.NewWave=addlistener(app.Instr,'NewWave',@(~,~)updatePlot(app));
            app.Listeners.NewData=addlistener(app.Instr,'NewData',@(~,~)updateGui(app));
            app.Listeners.NewSetting=addlistener(app.Instr,'NewSetting',@(~,~)updateGui(app));
            
            updateGui(app);
            
            % Start continuous data acquisition
            startPoll(app.Instr);
        end

        % Button pushed function: TracetoDAQButton
        function TracetoDAQButtonPushed(app, event)
            % Send the present trace to Daq
            triggerNewData(app.Instr);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            resetCounter(app.Instr.Trace);
        end

        % Close request function: ZiScopeFt
        function ZiScopeFtCloseRequest(app, event)
            % Delete instrument object, clear global variable and delete app
            deleteInstrGui(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiScopeFt = uifigure;
            app.ZiScopeFt.Name = 'UI Figure';
            app.ZiScopeFt.CloseRequestFcn = createCallbackFcn(app, @ZiScopeFtCloseRequest, true);
            app.UIAxes = uiaxes(app.ZiScopeFt);
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'PSD (V^2/Hz)')
            app.UIAxes.YScale = 'log';
            app.ScopenumberEditFieldLabel = uilabel(app.ZiScopeFt);
            app.ScopenumberEditFieldLabel.Text = 'Scope number';
            app.ScopenumberEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ScopenumberEditField.Editable = 'off';
            app.ScopenumberEditField.Value = 1;
            app.SignalinputEditFieldLabel = uilabel(app.ZiScopeFt);
            app.SignalinputEditFieldLabel.Text = 'Signal input';
            app.SignalinputEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.SignalinputEditField.Editable = 'off';
            app.SignalinputEditField.Value = 1;
            app.TracetoDAQButton = uibutton(app.ZiScopeFt, 'push');
            app.TracetoDAQButton.ButtonPushedFcn = createCallbackFcn(app, @TracetoDAQButtonPushed, true);
            app.TracetoDAQButton.Text = 'Trace to DAQ';
            app.PlotPanel = uipanel(app.ZiScopeFt);
            app.PlotPanel.Title = 'Plot';
            app.ylimitmodeLabel = uilabel(app.PlotPanel);
            app.ylimitmodeLabel.Text = 'y limit mode';
            app.YLimModeDropDown = uidropdown(app.PlotPanel);
            app.YLimModeDropDown.Items = {'auto', 'manual'};
            app.YLimModeDropDown.Value = 'manual';
            app.yminEditFieldLabel = uilabel(app.PlotPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.PlotPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.PlotPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.PlotPanel, 'numeric');
            app.AcquisitionPanel = uipanel(app.ZiScopeFt);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.NumberofpointsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.NumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsEditField.Value = 5000000;
            app.FFTRBWEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FFTRBWEditFieldLabel.Text = 'FFT RBW';
            app.FFTRBWEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FFTRBWEditField.Editable = 'off';
            app.SamplingrateptsEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.SamplingrateptsEditFieldLabel.Text = 'Sampling rate (pt/s)';
            app.SamplingrateptsEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.AveragingPanel = uipanel(app.ZiScopeFt);
            app.AveragingPanel.Title = 'Averaging';
            app.AvgtypeDropDownLabel = uilabel(app.AveragingPanel);
            app.AvgtypeDropDownLabel.Text = 'Avg type';
            app.AvgtypeDropDown = uidropdown(app.AveragingPanel);
            app.AvgtypeDropDown.Items = {'linear', 'exp'};
            app.AvgtypeDropDown.ItemsData = {'lin', 'exp'};
            app.AvgtypeDropDown.Value = 'lin';
            app.navgEditFieldLabel = uilabel(app.AveragingPanel);
            app.navgEditFieldLabel.Text = 'n avg';
            app.navgEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.navgEditField.Value = 10000;
            app.CompletedEditFieldLabel = uilabel(app.AveragingPanel);
            app.CompletedEditFieldLabel.Text = 'Completed';
            app.CompletedEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.CompletedEditField.Editable = 'off';
            app.RestartButton = uibutton(app.AveragingPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiZiScopeFt

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiScopeFt)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiScopeFt)
        end
    end
end