classdef DiodeLaserScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiodelaserscanUIFigure         matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ScanTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        ScanButton                     matlab.ui.control.Button
        TracetoDaqButton               matlab.ui.control.Button
        StartwavelengthEditFieldLabel  matlab.ui.control.Label
        StartwavelengthEditField       matlab.ui.control.NumericEditField
        StopwavelengthEditFieldLabel   matlab.ui.control.Label
        StopwavelengthEditField        matlab.ui.control.NumericEditField
        ScopechannelsPanel             matlab.ui.container.Panel
        DetectorDropDownLabel          matlab.ui.control.Label
        DetectorDropDown               matlab.ui.control.DropDown
        WavelengthoutDropDownLabel     matlab.ui.control.Label
        WavelengthoutDropDown          matlab.ui.control.DropDown
        SelectinstrumentsTab           matlab.ui.container.Tab
        ScopeDropDownLabel             matlab.ui.control.Label
        ScopeDropDown                  matlab.ui.control.DropDown
        LaserDropDownLabel             matlab.ui.control.Label
        LaserDropDown                  matlab.ui.control.DropDown
        SelectinstrumentfromInstrumentListLabel  matlab.ui.control.Label
        HelpTab                        matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
    end


    properties (Access = public)
        name;
        Laser; % Diode laser instrument object
        Scope; % Oscilloscope instrument object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Close request function: DiodelaserscanUIFigure
        function DiodelaserscanUIFigureCloseRequest(app, event)
            try
                delete(app.Laser)
            catch
            end
            try
                delete(app.Scope)
            catch
            end
            % Clean up variable to which app handle is assigned in the global workspace
            try
                evalin('base', sprintf('clear(''%s'')', app.name));
            catch
            end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiodelaserscanUIFigure = uifigure;
            app.DiodelaserscanUIFigure.Name = 'Diode laser scan';
            app.DiodelaserscanUIFigure.CloseRequestFcn = createCallbackFcn(app, @DiodelaserscanUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.DiodelaserscanUIFigure);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.UIAxes = uiaxes(app.ScanTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ScanButton = uibutton(app.ScanTab, 'push');
            app.ScanButton.Text = 'Scan';
            app.TracetoDaqButton = uibutton(app.ScanTab, 'push');
            app.TracetoDaqButton.Text = 'Trace to Daq';
            app.StartwavelengthEditFieldLabel = uilabel(app.ScanTab);
            app.StartwavelengthEditFieldLabel.Text = 'Start wavelength';
            app.StartwavelengthEditField = uieditfield(app.ScanTab, 'numeric');
            app.StopwavelengthEditFieldLabel = uilabel(app.ScanTab);
            app.StopwavelengthEditFieldLabel.Text = 'Stop wavelength';
            app.StopwavelengthEditField = uieditfield(app.ScanTab, 'numeric');
            app.ScopechannelsPanel = uipanel(app.ScanTab);
            app.ScopechannelsPanel.Title = 'Scope channels';
            app.DetectorDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.DetectorDropDownLabel.Text = 'Detector ';
            app.DetectorDropDown = uidropdown(app.ScopechannelsPanel);
            app.DetectorDropDown.Items = {'1', '2', '3', '4'};
            app.DetectorDropDown.Value = '1';
            app.WavelengthoutDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.WavelengthoutDropDownLabel.Text = 'Wavelength out';
            app.WavelengthoutDropDown = uidropdown(app.ScopechannelsPanel);
            app.WavelengthoutDropDown.Items = {'1', '2', '3', '4'};
            app.WavelengthoutDropDown.Value = '1';
            app.SelectinstrumentsTab = uitab(app.TabGroup);
            app.SelectinstrumentsTab.Title = 'Select instruments';
            app.ScopeDropDownLabel = uilabel(app.SelectinstrumentsTab);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.ScopeDropDown = uidropdown(app.SelectinstrumentsTab);
            app.LaserDropDownLabel = uilabel(app.SelectinstrumentsTab);
            app.LaserDropDownLabel.Text = 'Laser';
            app.LaserDropDown = uidropdown(app.SelectinstrumentsTab);
            app.SelectinstrumentfromInstrumentListLabel = uilabel(app.SelectinstrumentsTab);
            app.SelectinstrumentfromInstrumentListLabel.Text = 'Select instrument from InstrumentList';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Label = uilabel(app.HelpTab);
            app.Label.Text = {'This program scans wavelength of ECDL laser and records transmission vs wavelength'; 'Photodiode and Wavelength output of ECDL need to be connected to oscilloscope channels '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = DiodeLaserScan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiodelaserscanUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiodelaserscanUIFigure)
        end
    end
end