classdef DataAcquisitionMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataacquisitionmenuUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ProgramsTab                  matlab.ui.container.Tab
        ProgramTable                 matlab.ui.control.Table
        RunButton                    matlab.ui.control.Button
        InstrumentmanagerButton      matlab.ui.control.Button
        DeleterunfileButton          matlab.ui.control.Button
        ReloadlistButton             matlab.ui.control.Button
        RunfileheaderTextAreaLabel   matlab.ui.control.Label
        RunfileheaderTextArea        matlab.ui.control.TextArea
        SettingsTab                  matlab.ui.container.Tab
        MeasurementbasedirectoryEditFieldLabel  matlab.ui.control.Label
        MeasurementbasedirectoryEditField  matlab.ui.control.EditField
        LocalbasedirectoryEditFieldLabel  matlab.ui.control.Label
        LocalbasedirectoryEditField  matlab.ui.control.EditField
    end


    properties (Access = private)
        RunFiles %
        row_sel = 1; % selected row
        dir;
    end

    methods (Access = private)
    
        function updateProgramTable(app)
            tmp = menuFromRunFiles(app.RunFiles);
            app.ProgramTable.Data = tmp.titles;
            app.ProgramTable.ItemsData = tmp.tags;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dir = getLocalBaseDir();
            app.LocalbasedirectoryEditField.Value = app.dir;
            
            if ~isprop(app.ProgramTable,'ItemsData')
                addprop(app.ProgramTable,'ItemsData');
            end
            
            app.RunFiles = readRunFiles();
            updateProgramTable(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            tag = app.ProgramTable.ItemsData{app.row_sel};
            [~, fname, ~] = fileparts(app.RunFiles.(tag).fullname);
            feval(fname);
        end

        % Cell selection callback: ProgramTable
        function ProgramTableCellSelection(app, event)
            app.row_sel = event.Indices(1,1);
            tag = app.ProgramTable.ItemsData{app.row_sel};
            app.RunfileheaderTextArea.Value = app.RunFiles.(tag).header;
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            app.RunFiles = readRunFiles();
            updateProgramTable(app);
        end

        % Button pushed function: InstrumentmanagerButton
        function InstrumentmanagerButtonPushed(app, event)
            InstrumentManager();
        end

        % Button pushed function: DeleterunfileButton
        function DeleterunfileButtonPushed(app, event)
            tag = app.ProgramTable.ItemsData{app.row_sel};
            delete(app.RunFiles.(tag).fullname);
            app.RunFiles = readRunFiles();
            updateProgramTable(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataacquisitionmenuUIFigure = uifigure;
            app.DataacquisitionmenuUIFigure.Name = 'Data acquisition menu';
            app.TabGroup = uitabgroup(app.DataacquisitionmenuUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ProgramsTab = uitab(app.TabGroup);
            app.ProgramsTab.Title = 'Programs';
            app.ProgramTable = uitable(app.ProgramsTab);
            app.ProgramTable.ColumnName = {'Program'};
            app.ProgramTable.RowName = {};
            app.ProgramTable.CellSelectionCallback = createCallbackFcn(app, @ProgramTableCellSelection, true);
            app.RunButton = uibutton(app.ProgramsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InstrumentmanagerButton = uibutton(app.ProgramsTab, 'push');
            app.InstrumentmanagerButton.ButtonPushedFcn = createCallbackFcn(app, @InstrumentmanagerButtonPushed, true);
            app.InstrumentmanagerButton.Text = 'Instrument manager';
            app.DeleterunfileButton = uibutton(app.ProgramsTab, 'push');
            app.DeleterunfileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterunfileButtonPushed, true);
            app.DeleterunfileButton.Text = 'Delete run file';
            app.ReloadlistButton = uibutton(app.ProgramsTab, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.RunfileheaderTextAreaLabel = uilabel(app.ProgramsTab);
            app.RunfileheaderTextAreaLabel.Text = 'Run file header';
            app.RunfileheaderTextArea = uitextarea(app.ProgramsTab);
            app.RunfileheaderTextArea.Editable = 'off';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.MeasurementbasedirectoryEditFieldLabel = uilabel(app.SettingsTab);
            app.MeasurementbasedirectoryEditFieldLabel.Text = 'Measurement base directory';
            app.MeasurementbasedirectoryEditField = uieditfield(app.SettingsTab, 'text');
            app.MeasurementbasedirectoryEditField.Value = 'M:\Measurement Campaigns\';
            app.LocalbasedirectoryEditFieldLabel = uilabel(app.DataacquisitionmenuUIFigure);
            app.LocalbasedirectoryEditFieldLabel.Text = 'Local base directory';
            app.LocalbasedirectoryEditField = uieditfield(app.DataacquisitionmenuUIFigure, 'text');
            app.LocalbasedirectoryEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataAcquisitionMenu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataacquisitionmenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataacquisitionmenuUIFigure)
        end
    end
end