classdef InstrumentManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManagethelistofinstrumentsUIFigure  matlab.ui.Figure
        AddinstrumentButton          matlab.ui.control.Button
        DeleteinstrumentButton       matlab.ui.control.Button
        SavechangesButton            matlab.ui.control.Button
        ReloadlistButton             matlab.ui.control.Button
        ContinueButton               matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        InstrumentListlocationLabel  matlab.ui.control.Label
        BaseDirEditField             matlab.ui.control.EditField
        InstrumentsListBoxLabel      matlab.ui.control.Label
        InstrListBox                 matlab.ui.control.ListBox
        PropertiesPanel              matlab.ui.container.Panel
        ShowinDAQCheckBox            matlab.ui.control.CheckBox
        InterfaceEditFieldLabel      matlab.ui.control.Label
        InterfaceEditField           matlab.ui.control.EditField
        AddressEditFieldLabel        matlab.ui.control.Label
        AddressEditField             matlab.ui.control.EditField
        ControlclassEditFieldLabel   matlab.ui.control.Label
        ControlclassEditField        matlab.ui.control.EditField
        GUIEditFieldLabel            matlab.ui.control.Label
        GUIEditField                 matlab.ui.control.EditField
        MenutitleEditFieldLabel      matlab.ui.control.Label
        MenutitleEditField           matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
    end


    properties (Access = public)   
        TmpInstrList;
        Ret % variable with the value passed by reference to return the choosen device
        
        NORMAL_COLOR = [0.47, 0.67, 0.19];
        ERROR_COLOR = [1, 0, 0];
        WARNING_COLOR = [0.93, 0.69, 0.13];
        
        EDIT_BG_COLOR = [1,1,0.9]; % yellow if changes unsaved
        STD_BG_COLOR = [1,1,1]; % white
    end

    methods (Access = private)
    
        function updateInstrDisp(app)
            instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
            app.InstrListBox.Items = instr_names;
            % An element of ItemsData is returned when Value property is accessed
            app.InstrListBox.ItemsData = 1:length(instr_names);
            ind = app.InstrListBox.Value;
            if ~isempty(ind)
                app.NameEditField.Value = app.TmpInstrList{ind}.name;
                app.InterfaceEditField.Value = app.TmpInstrList{ind}.interface;
                app.AddressEditField.Value = app.TmpInstrList{ind}.address;
                try
                    app.ControlclassEditField.Value = app.TmpInstrList{ind}.control_class;
                catch
                    app.ControlclassEditField.Value = '';
                end
                try
                    app.GUIEditField.Value = app.TmpInstrList{ind}.gui;
                catch
                    app.GUIEditField.Value = '';
                end
                try
                    app.MenutitleEditField.Value = app.TmpInstrList{ind}.menu_title;
                catch
                    app.MenutitleEditField.Value = '';
                end
                try
                    app.ShowinDAQCheckBox.Value = app.TmpInstrList{ind}.show_in_daq;
                catch
                    app.ShowinDAQCheckBox.Value = false;
                end
            else
                app.NameEditField.Value = '';
                app.InterfaceEditField.Value = '';
                app.AddressEditField.Value = '';
                app.ControlclassEditField.Value = '';
                app.GUIEditField.Value = '';
                app.MenutitleEditField.Value = '';
                app.ShowinDAQCheckBox.Value = false;
            end
        end
    
        function updateTmpInstrList(app)           
            ind = app.InstrListBox.Value;
            app.TmpInstrList{ind}.name = app.NameEditField.Value;
            app.TmpInstrList{ind}.interface = app.InterfaceEditField.Value;
            app.TmpInstrList{ind}.address = app.AddressEditField.Value;
            app.TmpInstrList{ind}.control_class = app.ControlclassEditField.Value;
            app.TmpInstrList{ind}.gui = app.GUIEditField.Value;
            app.TmpInstrList{ind}.menu_title = app.MenutitleEditField.Value;
            app.TmpInstrList{ind}.show_in_daq = app.ShowinDAQCheckBox.Value;
            setStatus(app, 'edits_unsaved');
        end    
        
        function setStatus(app, status)
            switch status
                case 'edits_unsaved'
                    app.InstrListBox.BackgroundColor = app.EDIT_BG_COLOR;
                    app.StatusLabel.Text = 'List of instruments contains unsaved changes';
                    app.StatusLabel.FontColor = app.WARNING_COLOR;
                case 'edits_saved'
                    app.InstrListBox.BackgroundColor = app.STD_BG_COLOR;
                    app.StatusLabel.Text = 'Changes saved';
                    app.StatusLabel.FontColor = app.NORMAL_COLOR;
                otherwise
                    error('Unknown table status')
            end
        end       

        function saveInstrumentList(app)
            instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
            InstrumentList = cell2struct(app.TmpInstrList, instr_names);
            dir = getLocalBaseDir();
            file_name = fullfile(dir,'LocalInstrumentControlSettings.mat');
            save(file_name, 'InstrumentList','-append');
            setStatus(app, 'edits_saved');
        end
    
        function loadInstrumentList(app)
            app.TmpInstrList = struct2cell(getLocalSettings('InstrumentList'));                 
            updateInstrDisp(app);
            setStatus(app, 'edits_saved');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % The line below will throw an error if the settings file does not exist
            app.BaseDirEditField.Value = getLocalBaseDir();
            % Display instrument list
            loadInstrumentList(app);
            
            % The variable, to which the output will be assigned
            app.ContinueButton.Enable = 'off';
            if isempty(varargin)
                app.Ret = MyRefVar('');
            else
                if isequal(class(varargin{1}),'MyRefVar')
                    app.Ret = varargin{1};
                    % Enable the continue button only if appropriate MyRefVar
                    % was supplied to the input
                    app.ContinueButton.Enable = 'on';
                else
                    app.ErrorwarningTextArea.Value = ...
                        ['The input variable class needs to be MyRefVar.',... 
                        ' The selected value will not be returned'];
                    app.ErrorwarningTextArea.FontColor = app.WARNING_COLOR;
                    app.Ret = MyRefVar('');
                end
            end
        end

        % Button pushed function: AddinstrumentButton
        function AddinstrumentButtonPushed(app, event)
            newaddr = MyRefVar('');
            waitfor(FindInstrumentDlg(newaddr));
            if ~isequal(newaddr.value,'')
                instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
                tmp_name = genvarname('instrument', instr_names);
                app.TmpInstrList{end+1} = struct();
                app.TmpInstrList{end}.name = tmp_name;
                app.TmpInstrList{end}.interface = 'constructor';
                app.TmpInstrList{end}.address = newaddr.value;
                updateInstrDisp(app);
                setStatus(app, 'edits_unsaved');
            end
        end

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            saveInstrumentList(app);
        end

        % Button pushed function: ReloadlistButton
        function ReloadlistButtonPushed(app, event)
            loadInstrumentList(app);
        end

        % Button pushed function: DeleteinstrumentButton
        function DeleteinstrumentButtonPushed(app, event)
            ind = app.InstrListBox.Value;
            if ~isempty(ind)
                app.TmpInstrList(ind) = [];
                updateInstrDisp(app);
                setStatus(app, 'edits_unsaved');
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Save changes
            saveInstrumentList(app);
            % Find the selected table row
            name = getSelectedInstrumentName(app);
            app.Ret.value = InstrumentList.(name);
            delete(app)
        end

        % Value changed function: InstrListBox
        function InstrListBoxValueChanged(app, event)
            updateInstrDisp(app);
        end

        % Value changed function: AddressEditField, 
        % ControlclassEditField, GUIEditField, InterfaceEditField, 
        % MenutitleEditField, NameEditField, ShowinDAQCheckBox
        function InstrPropertyValueChanged(app, event)
            if ~isempty(app.InstrListBox.Value)
                updateTmpInstrList(app);
                instr_names = cellfun(@(x) x.name, app.TmpInstrList, 'UniformOutput', false);
                app.InstrListBox.Items = instr_names;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManagethelistofinstrumentsUIFigure = uifigure;
            app.ManagethelistofinstrumentsUIFigure.Name = 'Manage the list of instruments';
            app.AddinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.AddinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @AddinstrumentButtonPushed, true);
            app.AddinstrumentButton.Text = 'Add instrument';
            app.DeleteinstrumentButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.DeleteinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteinstrumentButtonPushed, true);
            app.DeleteinstrumentButton.Text = 'Delete instrument';
            app.SavechangesButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.ReloadlistButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ReloadlistButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadlistButtonPushed, true);
            app.ReloadlistButton.Text = 'Reload list';
            app.ContinueButton = uibutton(app.ManagethelistofinstrumentsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.StatusLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.StatusLabel.Text = 'Status';
            app.InstrumentListlocationLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentListlocationLabel.Text = 'InstrumentList location';
            app.BaseDirEditField = uieditfield(app.ManagethelistofinstrumentsUIFigure, 'text');
            app.BaseDirEditField.Editable = 'off';
            app.InstrumentsListBoxLabel = uilabel(app.ManagethelistofinstrumentsUIFigure);
            app.InstrumentsListBoxLabel.Text = 'Instruments';
            app.InstrListBox = uilistbox(app.ManagethelistofinstrumentsUIFigure);
            app.InstrListBox.ValueChangedFcn = createCallbackFcn(app, @InstrListBoxValueChanged, true);
            app.PropertiesPanel = uipanel(app.ManagethelistofinstrumentsUIFigure);
            app.PropertiesPanel.Title = 'Properties';
            app.ShowinDAQCheckBox = uicheckbox(app.PropertiesPanel);
            app.ShowinDAQCheckBox.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.ShowinDAQCheckBox.Text = 'Show in DAQ?';
            app.ShowinDAQCheckBox.Value = true;
            app.InterfaceEditFieldLabel = uilabel(app.PropertiesPanel);
            app.InterfaceEditFieldLabel.Text = 'Interface';
            app.InterfaceEditField = uieditfield(app.PropertiesPanel, 'text');
            app.InterfaceEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.AddressEditFieldLabel = uilabel(app.PropertiesPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.PropertiesPanel, 'text');
            app.AddressEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.ControlclassEditFieldLabel = uilabel(app.PropertiesPanel);
            app.ControlclassEditFieldLabel.Text = 'Control class';
            app.ControlclassEditField = uieditfield(app.PropertiesPanel, 'text');
            app.ControlclassEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.GUIEditFieldLabel = uilabel(app.PropertiesPanel);
            app.GUIEditFieldLabel.Text = 'GUI';
            app.GUIEditField = uieditfield(app.PropertiesPanel, 'text');
            app.GUIEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.MenutitleEditFieldLabel = uilabel(app.PropertiesPanel);
            app.MenutitleEditFieldLabel.Text = 'Menu title';
            app.MenutitleEditField = uieditfield(app.PropertiesPanel, 'text');
            app.MenutitleEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
            app.NameEditFieldLabel = uilabel(app.PropertiesPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PropertiesPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @InstrPropertyValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = InstrumentManager(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManagethelistofinstrumentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManagethelistofinstrumentsUIFigure)
        end
    end
end